classdef AddInfoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel_2               matlab.ui.container.Panel
        saveButton            matlab.ui.control.Button
        additionalTextArea    matlab.ui.control.TextArea
        Label_7               matlab.ui.control.Label
        ButtonGroup_13        matlab.ui.container.ButtonGroup
        Button_13Y            matlab.ui.control.RadioButton
        Button_13N            matlab.ui.control.RadioButton
        ButtonGroup_12        matlab.ui.container.ButtonGroup
        Button_12Y            matlab.ui.control.RadioButton
        Button_12N            matlab.ui.control.RadioButton
        ButtonGroup_11        matlab.ui.container.ButtonGroup
        Button_11Y            matlab.ui.control.RadioButton
        Button_11N            matlab.ui.control.RadioButton
        ButtonGroup_10        matlab.ui.container.ButtonGroup
        Button_10Y            matlab.ui.control.RadioButton
        Button_10N            matlab.ui.control.RadioButton
        ButtonGroup_9         matlab.ui.container.ButtonGroup
        Button_9Y             matlab.ui.control.RadioButton
        Button_9N             matlab.ui.control.RadioButton
        ButtonGroup_8         matlab.ui.container.ButtonGroup
        Button_8Y             matlab.ui.control.RadioButton
        Button_8N             matlab.ui.control.RadioButton
        ButtonGroup_7         matlab.ui.container.ButtonGroup
        Button_7Y             matlab.ui.control.RadioButton
        Button_7N             matlab.ui.control.RadioButton
        ButtonGroup_6         matlab.ui.container.ButtonGroup
        Button_6Y             matlab.ui.control.RadioButton
        Button_6N             matlab.ui.control.RadioButton
        ButtonGroup_5         matlab.ui.container.ButtonGroup
        Button_5Y             matlab.ui.control.RadioButton
        Button_5N             matlab.ui.control.RadioButton
        diastEditField        matlab.ui.control.NumericEditField
        Label_6               matlab.ui.control.Label
        saturationEditField   matlab.ui.control.NumericEditField
        Label_5               matlab.ui.control.Label
        systEditField         matlab.ui.control.NumericEditField
        Label_4               matlab.ui.control.Label
        temperatureEditField  matlab.ui.control.NumericEditField
        Label_3               matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        patientLabel          matlab.ui.control.Label
        startButton           matlab.ui.control.Button
        pLabel                matlab.ui.control.Label
        findButton            matlab.ui.control.Button
        pasportEditField      matlab.ui.control.EditField
        Label_2               matlab.ui.control.Label
        exitButton            matlab.ui.control.Button
        Label                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Окно, из которого было открыто данное окно
        patientID
        day
        temperature
        saturation
        syst_pressure
        diast_pressure
        cough
        fatigue
        smell_loss
        dyspnea
        digestion_problems
        appetite_loss
        head_ache
        muscle_pain
        chest_compression
        extra_info
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CallingApp = MainApp;
        end

        % Callback function: UIFigure, exitButton
        function UIFigureCloseRequest(app, event)
            app.CallingApp.addInfoButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: findButton
        function findButtonPushed(app, event)
            inputNumber = app.pasportEditField.Value;
            dbfile = fullfile(pwd,"database.db"); 
            conn = sqlite(dbfile);
            selectquery = "SELECT * FROM Patients WHERE pasport = " + inputNumber;
            selectedData = fetch(conn,selectquery);
            close(conn);
            if height(selectedData) ~= 0
                patientFIO = selectedData.surname(1)+" "+selectedData.name(1)+...
                    " "+selectedData.lastname(1);
                app.patientID = selectedData.id(1);
                app.patientLabel.Text = patientFIO + " ID: " + app.patientID;
                app.day = selectedData.day(1);
                if app.day == 14
                    dialog = uiconfirm(app.UIFigure,['Цикл тестирования ' ...
                    'для данного пациента уже завершен.'], 'Заполнение опроса', ...
                    'Options', {'OK'});
                else
                    app.startButton.Enable = "on";
                end
            else
                app.startButton.Enable = "off";
                app.patientLabel.Text = "Не найден";
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.findButton.Enable = "off";
            app.startButton.Enable = "off";
            app.temperatureEditField.Enable = "on";
            app.saturationEditField.Enable = "on";
            app.systEditField.Enable = "on";
            app.diastEditField.Enable = "on";
            
           
            app.ButtonGroup_5.Enable = "on";
            app.ButtonGroup_6.Enable = "on";
            app.ButtonGroup_7.Enable = "on";
            app.ButtonGroup_8.Enable = "on";
            app.ButtonGroup_9.Enable = "on";
            app.ButtonGroup_10.Enable = "on";
            app.ButtonGroup_11.Enable = "on";
            app.ButtonGroup_12.Enable = "on";
            app.ButtonGroup_13.Enable = "on";

            app.Button_5Y.Enable = "on";
            app.Button_5N.Enable = "on";
            app.Button_6Y.Enable = "on";
            app.Button_6N.Enable = "on";
            app.Button_7Y.Enable = "on";
            app.Button_7N.Enable = "on";
            app.Button_8Y.Enable = "on";
            app.Button_8N.Enable = "on";
            app.Button_9Y.Enable = "on";
            app.Button_9N.Enable = "on";
            app.Button_10Y.Enable = "on";
            app.Button_10N.Enable = "on";
            app.Button_11Y.Enable = "on";
            app.Button_11N.Enable = "on";
            app.Button_12Y.Enable = "on";
            app.Button_12N.Enable = "on";
            app.Button_13Y.Enable = "on";
            app.Button_13N.Enable = "on";

            
            app.saveButton.Enable = "on";
            app.additionalTextArea.Enable = "on";

            app.Label.Enable = "on";
            app.Label_2.Enable = "on";
            app.Label_3.Enable = "on";
            app.Label_4.Enable = "on";
            app.Label_5.Enable = "on";
            app.Label_6.Enable = "on";
            app.Label_7.Enable = "on";

            
            app.temperature = 0;
            app.saturation = 0;
            app.syst_pressure = 0;
            app.diast_pressure = 0;
            app.cough = "Нет";
            app.fatigue = "Нет";
            app.smell_loss = "Нет";
            app.digestion_problems = "Нет";
            app.dyspnea = "Нет";
            app.appetite_loss = "Нет";
            app.head_ache = "Нет";
            app.muscle_pain = "Нет";
            app.chest_compression = "Нет";
            app.extra_info = "";
        end

        % Selection changed function: ButtonGroup_5
        function ButtonGroup_5SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_5.SelectedObject;
            if selectedButton.Text == "Да"
                app.cough = "Да";
            else
                app.cough = "Нет";
            end
        end

        % Selection changed function: ButtonGroup_6
        function ButtonGroup_6SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_6.SelectedObject;
            if selectedButton.Text == "Да"
                app.fatigue = "Да";
            else
                app.fatigue = "Нет";
            end
        end

        % Selection changed function: ButtonGroup_7
        function ButtonGroup_7SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_7.SelectedObject;
            if selectedButton.Text == "Да"
                app.smell_loss = "Да";
            else
                app.smell_loss = "Нет";
            end
        end

        % Selection changed function: ButtonGroup_8
        function ButtonGroup_8SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_8.SelectedObject;
            if selectedButton.Text == "Да"
                app.digestion_problems = "Да";
            else
                app.digestion_problems = "Нет";
            end
        end

        % Selection changed function: ButtonGroup_9
        function ButtonGroup_9SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_9.SelectedObject;
            if selectedButton.Text == "Да"
                app.dyspnea = "Да";
            else
                app.dyspnea = "Нет";
            end
        end

        % Selection changed function: ButtonGroup_10
        function ButtonGroup_10SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_10.SelectedObject;
            if selectedButton.Text == "Да"
                app.appetite_loss = "Да";
            else
                app.appetite_loss = "Нет";
            end
        end

        % Selection changed function: ButtonGroup_11
        function ButtonGroup_11SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_11.SelectedObject;
            if selectedButton.Text == "Да"
                app.head_ache = "Да";
            else
                app.head_ache = "Нет";
            end
        end

        % Selection changed function: ButtonGroup_12
        function ButtonGroup_12SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_12.SelectedObject;
            if selectedButton.Text == "Да"
                app.muscle_pain = "Да";
            else
                app.muscle_pain = "Нет";
            end
        end

        % Selection changed function: ButtonGroup_13
        function ButtonGroup_13SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_13.SelectedObject;
            if selectedButton.Text == "Да"
                app.chest_compression = "Да";
            else
                app.chest_compression = "Нет";
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.temperature = num2str(app.temperatureEditField.Value);
            app.saturation = num2str(app.saturationEditField.Value);
            app.syst_pressure = num2str(app.systEditField.Value);
            app.diast_pressure = num2str(app.diastEditField.Value);

            
            app.extra_info = compose(app.additionalTextArea.Value);
            
            dateFormat = 'dd.mm.yyyy';
            timeFormat = 'HH:MM';
            date = datestr(now,dateFormat);
            time = datestr(now,timeFormat);

            app.day = app.day + 1;

            dataArray = [num2str(app.patientID) num2str(app.day) date time  app.temperature ...
                    app.saturation app.syst_pressure app.diast_pressure ...
                    app.cough app.fatigue app.smell_loss app.digestion_problems ...
                    app.dyspnea app.appetite_loss app.head_ache app.muscle_pain ...
                    app.chest_compression app.extra_info];
            dataTable = array2table(dataArray,'VariableNames',{'patient_id',...
                'day','date','time','temperature', 'saturation', 'syst_pressure',...
                'diast_pressure','cough','fatigue','smell_loss','digestion_problems',...
                'dyspnea','appetite_loss','head_ache','muscle_pain', 'chest_compression',...
                'extra_info'});
            dbfile = fullfile(pwd,"database.db"); 
            conn = sqlite(dbfile);
            sqlwrite(conn,"Protocols", dataTable);
            sqlquerry = "UPDATE Patients SET day = " + app.day + " WHERE id = " + app.patientID;
            execute(conn,sqlquerry);
            close(conn);
                dialog = uiconfirm(app.UIFigure,'Данные записаны успешно!', 'Добавление протокола в базу', ...
                    'Options', {'OK'});
            app.CallingApp.addInfoButton.Enable = "on";
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Ввод данных о состоянии пациента';
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.exitButton.Text = 'Выход';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Выбор пациента';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Номер паспорта';
            app.pasportEditField = uieditfield(app.Panel, 'text');
            app.findButton = uibutton(app.Panel, 'push');
            app.findButton.ButtonPushedFcn = createCallbackFcn(app, @findButtonPushed, true);
            app.findButton.Text = 'Найти';
            app.pLabel = uilabel(app.Panel);
            app.pLabel.Text = 'Пациент:';
            app.startButton = uibutton(app.Panel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Enable = 'off';
            app.startButton.Text = 'Начать';
            app.patientLabel = uilabel(app.Panel);
            app.patientLabel.Text = 'Не найден';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Ввод диагностической информации';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '1. t тела,  °C';
            app.temperatureEditField = uieditfield(app.Panel_2, 'numeric');
            app.temperatureEditField.Enable = 'off';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '3. Сист-е давл., мм. рт. ст.';
            app.systEditField = uieditfield(app.Panel_2, 'numeric');
            app.systEditField.Enable = 'off';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Enable = 'off';
            app.Label_5.Text = '2. Сатурация, %';
            app.saturationEditField = uieditfield(app.Panel_2, 'numeric');
            app.saturationEditField.Enable = 'off';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = '4. Диаст-е давл., мм. рт. ст.';
            app.diastEditField = uieditfield(app.Panel_2, 'numeric');
            app.diastEditField.Enable = 'off';
            app.ButtonGroup_5 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_5SelectionChanged, true);
            app.ButtonGroup_5.Enable = 'off';
            app.ButtonGroup_5.Title = '5. Наличие кашля';
            app.Button_5N = uiradiobutton(app.ButtonGroup_5);
            app.Button_5N.Enable = 'off';
            app.Button_5N.Text = 'Нет';
            app.Button_5N.Value = true;
            app.Button_5Y = uiradiobutton(app.ButtonGroup_5);
            app.Button_5Y.Enable = 'off';
            app.Button_5Y.Text = 'Да';
            app.ButtonGroup_6 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_6.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_6SelectionChanged, true);
            app.ButtonGroup_6.Enable = 'off';
            app.ButtonGroup_6.Title = '6. Повышенная утомляемость';
            app.Button_6N = uiradiobutton(app.ButtonGroup_6);
            app.Button_6N.Enable = 'off';
            app.Button_6N.Text = 'Нет';
            app.Button_6N.Value = true;
            app.Button_6Y = uiradiobutton(app.ButtonGroup_6);
            app.Button_6Y.Enable = 'off';
            app.Button_6Y.Text = 'Да';
            app.ButtonGroup_7 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_7.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_7SelectionChanged, true);
            app.ButtonGroup_7.Enable = 'off';
            app.ButtonGroup_7.Title = '7. Потеря запахов/вкусов';
            app.Button_7N = uiradiobutton(app.ButtonGroup_7);
            app.Button_7N.Enable = 'off';
            app.Button_7N.Text = 'Нет';
            app.Button_7N.Value = true;
            app.Button_7Y = uiradiobutton(app.ButtonGroup_7);
            app.Button_7Y.Enable = 'off';
            app.Button_7Y.Text = 'Да';
            app.ButtonGroup_8 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_8.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_8SelectionChanged, true);
            app.ButtonGroup_8.Enable = 'off';
            app.ButtonGroup_8.Title = '8. Наличие диареи, тошноты, рвоты';
            app.Button_8N = uiradiobutton(app.ButtonGroup_8);
            app.Button_8N.Enable = 'off';
            app.Button_8N.Text = 'Нет';
            app.Button_8N.Value = true;
            app.Button_8Y = uiradiobutton(app.ButtonGroup_8);
            app.Button_8Y.Enable = 'off';
            app.Button_8Y.Text = 'Да';
            app.ButtonGroup_9 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_9.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_9SelectionChanged, true);
            app.ButtonGroup_9.Enable = 'off';
            app.ButtonGroup_9.Title = '9. Наличие одышки, учащенного дыхания';
            app.Button_9N = uiradiobutton(app.ButtonGroup_9);
            app.Button_9N.Enable = 'off';
            app.Button_9N.Text = 'Нет';
            app.Button_9N.Value = true;
            app.Button_9Y = uiradiobutton(app.ButtonGroup_9);
            app.Button_9Y.Enable = 'off';
            app.Button_9Y.Text = 'Да';
            app.ButtonGroup_10 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_10.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_10SelectionChanged, true);
            app.ButtonGroup_10.Enable = 'off';
            app.ButtonGroup_10.Title = '10. Потеря аппетита';
            app.Button_10N = uiradiobutton(app.ButtonGroup_10);
            app.Button_10N.Enable = 'off';
            app.Button_10N.Text = 'Нет';
            app.Button_10N.Value = true;
            app.Button_10Y = uiradiobutton(app.ButtonGroup_10);
            app.Button_10Y.Enable = 'off';
            app.Button_10Y.Text = 'Да';
            app.ButtonGroup_11 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_11.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_11SelectionChanged, true);
            app.ButtonGroup_11.Enable = 'off';
            app.ButtonGroup_11.Title = '11. Ощущение головной боли/головокружения';
            app.Button_11N = uiradiobutton(app.ButtonGroup_11);
            app.Button_11N.Enable = 'off';
            app.Button_11N.Text = 'Нет';
            app.Button_11N.Value = true;
            app.Button_11Y = uiradiobutton(app.ButtonGroup_11);
            app.Button_11Y.Enable = 'off';
            app.Button_11Y.Text = 'Да';
            app.ButtonGroup_12 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_12.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_12SelectionChanged, true);
            app.ButtonGroup_12.Enable = 'off';
            app.ButtonGroup_12.Title = '12. Ощущение боли в суставах и мышцах';
            app.Button_12N = uiradiobutton(app.ButtonGroup_12);
            app.Button_12N.Enable = 'off';
            app.Button_12N.Text = 'Нет';
            app.Button_12N.Value = true;
            app.Button_12Y = uiradiobutton(app.ButtonGroup_12);
            app.Button_12Y.Enable = 'off';
            app.Button_12Y.Text = 'Да';
            app.ButtonGroup_13 = uibuttongroup(app.Panel_2);
            app.ButtonGroup_13.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_13SelectionChanged, true);
            app.ButtonGroup_13.Enable = 'off';
            app.ButtonGroup_13.Title = '13. Ощущение сдавливания в грудной клетке';
            app.Button_13N = uiradiobutton(app.ButtonGroup_13);
            app.Button_13N.Enable = 'off';
            app.Button_13N.Text = 'Нет';
            app.Button_13N.Value = true;
            app.Button_13Y = uiradiobutton(app.ButtonGroup_13);
            app.Button_13Y.Enable = 'off';
            app.Button_13Y.Text = 'Да';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Enable = 'off';
            app.Label_7.Text = 'Комментарий о самочувствии';
            app.additionalTextArea = uitextarea(app.Panel_2);
            app.additionalTextArea.Enable = 'off';
            app.saveButton = uibutton(app.Panel_2, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Enable = 'off';
            app.saveButton.Text = 'Внести данные';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddInfoApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end