classdef DBAccessApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button             matlab.ui.control.Button
        passwordEditField  matlab.ui.control.EditField
        Label_4            matlab.ui.control.Label
        doctorDropDown     matlab.ui.control.DropDown
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Окно, из которого было открыто данное окно
        dbAccessApp % Окно просмотра БД
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CallingApp = MainApp;
            dbfile = fullfile(pwd,"database.db"); 
            conn = sqlite(dbfile);
            doctorsTable = sqlread(conn,"Doctors");
            close(conn);
            for i = 1:length(doctorsTable.id)
                name = doctorsTable.name(i);
                surname = doctorsTable.surname(i);
                lastname = doctorsTable.lastname(i);
                id = doctorsTable.id(i);
                item = surname+" "+name+" "+lastname+" (ID: " + id + ")";
                app.doctorDropDown.Items = [app.doctorDropDown.Items item];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.viewDBButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            doctor_id =  regexp(compose(app.doctorDropDown.Value),'\d*','Match');
            dbfile = fullfile(pwd,"database.db"); 
            conn = sqlite(dbfile);
            selectquery = "SELECT * FROM Doctors WHERE id = " + doctor_id;
            selectedDoctorData = fetch(conn,selectquery);
            close(conn);
            rightPassword = selectedDoctorData.password(1);

            if app.passwordEditField.Value == rightPassword
                app.CallingApp.viewDBApp = ViewDBApp(app.CallingApp, doctor_id);
                delete(app)
            else
                dialog = uiconfirm(app.UIFigure,['Вы ввели неверный пароль! ' ...
                    'Повторите попытку.'], 'Авторизация', ...
                    'Options', {'OK'});
                app.passwordEditField.Value = "";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Переход к просмотру базы данных';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Выберите себя в списке ниже и введите индивидаульный пароль доступа';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Врач';
            app.doctorDropDown = uidropdown(app.UIFigure);
            app.doctorDropDown.Items = {};
            app.doctorDropDown.Value = {};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Пароль';
            app.passwordEditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Войти';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DBAccessApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end