classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Menu              matlab.ui.container.Menu
        addDoctorMenu     matlab.ui.container.Menu
        exitMenu          matlab.ui.container.Menu
        Menu_8            matlab.ui.container.Menu
        helpMenu          matlab.ui.container.Menu
        aboutMenu         matlab.ui.container.Menu
        addRes            matlab.ui.control.Button
        COVIDLabel        matlab.ui.control.Label
        COVDiHISLabel     matlab.ui.control.Label
        viewDBButton      matlab.ui.control.Button
        addInfoButton     matlab.ui.control.Button
        addPatientButton  matlab.ui.control.Button
        Image             matlab.ui.control.Image
    end

    
    properties (Access = public)
        newPatientApp % Окно добавления нового пациента в БД
        addInfoApp % Окно внесения диагностической информации
        viewDBApp % Окно просмотра БД
        newDoctorApp % Окно добавления нового врача в БД
        dbAccessApp % Окно ввода пароля доступа к БД
        helpApp % Окно справки
        aboutApp % Окно о программе
        Lab
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isfile('database.db')
                 app.addDoctorMenu.Enable = "on";
                 app.viewDBButton.Enable = "on";
                 app.addInfoButton.Enable = "on";
                 app.addPatientButton.Enable = "on";
                 app.addRes.Enable = "on";
            else
                 YN = uiconfirm(app.UIFigure,'Создать базу данных?', 'БД отсутствует', ...
                'Options', {'OK','Отмена'});
                if strcmpi(YN,'OK')
                    createDBMenuSelected(app);
                end
            end
        end

        % Button pushed function: addPatientButton
        function addPatientButtonPushed(app, event)
            app.addPatientButton.Enable = 'off';
            app.newPatientApp = NewPatientApp(app);
        end

        % Callback function: UIFigure, exitMenu
        function UIFigureCloseRequest(app, event)
            delete(app.newPatientApp)
            delete(app.addInfoApp)
            delete(app.viewDBApp)
            delete(app.newDoctorApp)
            delete(app.dbAccessApp)
            delete(app.helpApp)
            delete(app)
            
        end

        % Button pushed function: addInfoButton
        function addInfoButtonPushed(app, event)
            app.addInfoButton.Enable = 'off';
            app.addInfoApp = AddInfoApp(app);
        end

        % Button pushed function: viewDBButton
        function viewDBButtonPushed(app, event)
            app.viewDBButton.Enable = 'off';
            app.dbAccessApp = DBAccessApp(app);
            %app.viewDBApp = ViewDBApp(app);
        end

        % Menu selected function: addDoctorMenu
        function addDoctorMenuSelected(app, event)
            app.addDoctorMenu.Enable = 'off';
            app.newDoctorApp = NewDoctorApp(app);
        end

        % Callback function
        function createDBMenuSelected(app, event)
            dbfile = fullfile(pwd,"database.db"); 
            conn = sqlite(dbfile,"create");
            sqlquery = strcat("CREATE TABLE if not exists " + ...
                "Doctors(id INTEGER PRIMARY KEY AUTOINCREMENT, ", ...
                "surname TEXT, name TEXT, lastname TEXT, birth_date TEXT, " + ...
                "password TEXT)");
            execute(conn, sqlquery);
            sqlquery = strcat("CREATE TABLE if not exists " + ...
                "Patients(id INTEGER PRIMARY KEY AUTOINCREMENT, ", ...
                "doctor_id INTEGER, surname TEXT, name TEXT, lastname TEXT, " + ...
                "birth_date TEXT, gender TEXT, day INETEGER, pasport TEXT, polis TEXT," + ...
                "reg_address TEXT, fact_address TEXT, smoke TEXT, " + ...
                "cardiac_pathologies TEXT, diabetes TEXT, hypertonia TEXT, " + ...
                "bm_index TEXT, extra_info TEXT)");
            execute(conn, sqlquery);
            sqlquery = strcat("CREATE TABLE if not exists " + ...
                "Protocols(id INTEGER PRIMARY KEY AUTOINCREMENT, ", ...
                "patient_id INTEGER, day TEXT, date TEXT, time TEXT, " + ...
                "temperature REAL, saturation REAL, syst_pressure REAL, " + ...
                "diast_pressure REAL, cough INTEGER, fatigue INTEGER, " + ...
                "smell_loss INTEGER, digestion_problems INTEGER, dyspnea INTEGER, " + ...
                "appetite_loss INTEGER, head_ache INTEGER, muscle_pain INTEGER, " + ...
                "chest_compression INTEGER, extra_info TEXT)");
            execute(conn, sqlquery);
            sqlquery = strcat("CREATE TABLE if not exists " + ...
                "Analyzes(id INTEGER PRIMARY KEY AUTOINCREMENT, ", ...
                "patient_id INTEGER, date TEXT, time TEXT, " + ...
                "IgM REAL, IgG REAL, SOE REAL, " + ...
                "Hemoglabin REAL, Leycocit REAL, ALAT REAL)");
            execute(conn, sqlquery);
            close(conn);
            app.addDoctorMenu.Enable = "on";
            app.viewDBButton.Enable = "on";
            app.addInfoButton.Enable = "on";
            app.addPatientButton.Enable = "on";
            app.addRes.Enable = "on";
        end

        % Callback function
        function openDBMenuSelected(app, event)
            [file,path] = uigetfile('*.db*');
            if isequal(file,0)
                % файл не загружен
            else
                directory = fullfile(path,file);
                copyfile(directory, fullfile(pwd,"database.db"));
                app.addDoctorMenu.Enable = "on";
                app.viewDBButton.Enable = "on";
                app.addInfoButton.Enable = "on";
                app.addPatientButton.Enable = "on";
                app.addRes.Enable = "on";
            end
        end

        % Menu selected function: helpMenu
        function helpMenuSelected(app, event)
            app.helpApp.Enable = 'off';
            app.helpApp = HelpApp(app);
        end

        % Menu selected function: aboutMenu
        function aboutMenuSelected(app, event)
            uiconfirm(app.UIFigure,["Данная программа разработана для дисциплины 'Информационные системы в цифровой медицине'."+newline+"V 1.0"+newline+"Разработчик: " ...
                "Винограденко "+"Юлия "+"Владимировна"+newline+"Здесь могла быть ваша реклама"], 'Информация', ...
                'Options', {'OK'});
        end

        % Button pushed function: addRes
        function addResButtonPushed(app, event)
            app.addRes.Enable = 'off';
            app.Lab = labInfo(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Файл';
            app.addDoctorMenu = uimenu(app.Menu);
            app.addDoctorMenu.MenuSelectedFcn = createCallbackFcn(app, @addDoctorMenuSelected, true);
            app.addDoctorMenu.Enable = 'off';
            app.addDoctorMenu.Text = 'Внесение врача в БД...';
            app.exitMenu = uimenu(app.Menu);
            app.exitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.exitMenu.Text = 'Выход';
            app.Menu_8 = uimenu(app.UIFigure);
            app.Menu_8.Text = 'Помощь';
            app.helpMenu = uimenu(app.Menu_8);
            app.helpMenu.MenuSelectedFcn = createCallbackFcn(app, @helpMenuSelected, true);
            app.helpMenu.Text = 'Справка';
            app.aboutMenu = uimenu(app.Menu_8);
            app.aboutMenu.MenuSelectedFcn = createCallbackFcn(app, @aboutMenuSelected, true);
            app.aboutMenu.Text = 'О программе';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'img.png');
            app.addPatientButton = uibutton(app.UIFigure, 'push');
            app.addPatientButton.ButtonPushedFcn = createCallbackFcn(app, @addPatientButtonPushed, true);
            app.addPatientButton.Enable = 'off';
            app.addPatientButton.Text = 'Добавить пациента';
            app.addInfoButton = uibutton(app.UIFigure, 'push');
            app.addInfoButton.ButtonPushedFcn = createCallbackFcn(app, @addInfoButtonPushed, true);
            app.addInfoButton.Enable = 'off';
            app.addInfoButton.Text = 'Приём';
            app.viewDBButton = uibutton(app.UIFigure, 'push');
            app.viewDBButton.ButtonPushedFcn = createCallbackFcn(app, @viewDBButtonPushed, true);
            app.viewDBButton.Enable = 'off';
            app.viewDBButton.Text = 'База данных пациентов';
            app.COVDiHISLabel = uilabel(app.UIFigure);
            app.COVDiHISLabel.Text = 'COVDi HIS';
            app.COVIDLabel = uilabel(app.UIFigure);
            app.COVIDLabel.Text = 'Данная мидицинская информацтонная система преднаязначена для диагностики COVID ';
            app.addRes = uibutton(app.UIFigure, 'push');
            app.addRes.ButtonPushedFcn = createCallbackFcn(app, @addResButtonPushed, true);
            app.addRes.Enable = 'off';
            app.addRes.Text = 'Добавить результаты анализов';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end