classdef NewDoctorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        saveButton         matlab.ui.control.Button
        exitButton         matlab.ui.control.Button
        Panel              matlab.ui.container.Panel
        passwordEditField  matlab.ui.control.EditField
        Label_6            matlab.ui.control.Label
        birthDatePicker    matlab.ui.control.DatePicker
        Label_5            matlab.ui.control.Label
        lastnameEditField  matlab.ui.control.EditField
        Label_4            matlab.ui.control.Label
        nameEditField      matlab.ui.control.EditField
        Label_3            matlab.ui.control.Label
        surnameEditField   matlab.ui.control.EditField
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Окно, из которого было открыто данное окно
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CallingApp = MainApp;
        end

        % Callback function: UIFigure, exitButton
        function UIFigureCloseRequest(app, event)
            app.CallingApp.addDoctorMenu.Enable = 'on';
            delete(app)
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            if (app.passwordEditField.Value ~= "" && ...
            app.birthDatePicker.Value ~= "" && ...
            app.nameEditField.Value ~= "" && ...
            app.surnameEditField.Value ~= "" && ...
            app.lastnameEditField.Value ~= "")
                surname = compose(app.surnameEditField.Value);
                name = compose(app.nameEditField.Value);
                lastname = compose(app.lastnameEditField.Value);
                dateFormat = 'dd.mm.yyyy';
                birth_date = datestr(app.birthDatePicker.Value, dateFormat);
                password = compose(app.passwordEditField.Value);
                dataArray = [surname name lastname birth_date password];
                dataTable = array2table(dataArray,'VariableNames',{'surname','name','lastname','birth_date','password'});
                dbfile = fullfile(pwd,"database.db"); 
                conn = sqlite(dbfile);
                sqlwrite(conn,"Doctors", dataTable);
                close(conn);
                dialog = uiconfirm(app.UIFigure,'Данные записаны успешно!', 'Добавление врача в базу', ...
                    'Options', {'OK'});
            else
                dialog = uiconfirm(app.UIFigure,'Вы заполнили не все поля!', 'Добавление врача в базу', ...
                    'Options', {'OK'});
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Добавление нового врача в базу данных';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Ввод регистрационных данных';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Фамилия';
            app.surnameEditField = uieditfield(app.Panel, 'text');
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'Имя';
            app.nameEditField = uieditfield(app.Panel, 'text');
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = 'Отчество';
            app.lastnameEditField = uieditfield(app.Panel, 'text');
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = 'Дата рождения';
            app.birthDatePicker = uidatepicker(app.Panel);
            app.birthDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.birthDatePicker.Placeholder = 'дд.ММ.гггг';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = 'Пароль доступа';
            app.passwordEditField = uieditfield(app.Panel, 'text');
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.exitButton.Text = 'Выход';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Добавить';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewDoctorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end