classdef NewPatientApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        exitButton            matlab.ui.control.Button
        saveButton            matlab.ui.control.Button
        additionalTextArea    matlab.ui.control.TextArea
        Label_14              matlab.ui.control.Label
        Panel_2               matlab.ui.container.Panel
        doctorDropDown        matlab.ui.control.DropDown
        Label_15              matlab.ui.control.Label
        bmIndexEditField      matlab.ui.control.NumericEditField
        Label_11              matlab.ui.control.Label
        hypertoniaCheckBox    matlab.ui.control.CheckBox
        diabetesCheckBox      matlab.ui.control.CheckBox
        cardioCheckBox        matlab.ui.control.CheckBox
        smokeCheckBox         matlab.ui.control.CheckBox
        Panel                 matlab.ui.container.Panel
        residenceTextArea     matlab.ui.control.TextArea
        Label_13              matlab.ui.control.Label
        registrationTextArea  matlab.ui.control.TextArea
        Label_12              matlab.ui.control.Label
        polisEditField        matlab.ui.control.EditField
        Label_8               matlab.ui.control.Label
        pasportEditField      matlab.ui.control.EditField
        Label_7               matlab.ui.control.Label
        genderDropDown        matlab.ui.control.DropDown
        Label_6               matlab.ui.control.Label
        birthDatePicker       matlab.ui.control.DatePicker
        Label_5               matlab.ui.control.Label
        lastnameEditField     matlab.ui.control.EditField
        Label_4               matlab.ui.control.Label
        nameEditField         matlab.ui.control.EditField
        Label_3               matlab.ui.control.Label
        surnameEditField      matlab.ui.control.EditField
        Label_2               matlab.ui.control.Label
        Label                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Окно, из которого было открыто данное окно
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CallingApp = MainApp;
            dbfile = fullfile(pwd,"database.db"); 
            conn = sqlite(dbfile);
            doctorsTable = sqlread(conn,"Doctors");
            close(conn);
            for i = 1:length(doctorsTable.id)
                name = doctorsTable.name(i);
                surname = doctorsTable.surname(i);
                lastname = doctorsTable.lastname(i);
                id = doctorsTable.id(i);
                item = surname+" "+name+" "+lastname+" (ID: " + id + ")";
                app.doctorDropDown.Items = [app.doctorDropDown.Items item];
            end
        end

        % Callback function: UIFigure, exitButton
        function UIFigureCloseRequest(app, event)
            app.CallingApp.addPatientButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            if (app.surnameEditField.Value ~= "" && ...
            app.nameEditField.Value ~= "" && ...
            app.lastnameEditField.Value ~= "" && ...
            app.birthDatePicker.Value ~= "" && ...
            app.genderDropDown.Value ~= "" && ...
            app.pasportEditField.Value ~= "" && ...
            app.polisEditField.Value ~= "" && ...
            app.registrationTextArea.Value ~= "" && ...
            app.residenceTextArea.Value ~= "")
                if (app.smokeCheckBox.Value == 1)
                    smoke = "Да";
                else
                    smoke = "Нет";
                end
                if (app.cardioCheckBox.Value == 1)
                    cardiac_pathologies = "Да";
                else
                    cardiac_pathologies = "Нет";
                end
                if (app.cardioCheckBox.Value == 1)
                    cardiac_pathologies = "Да";
                else
                    cardiac_pathologies = "Нет";
                end
                if (app.diabetesCheckBox.Value == 1)
                    diabetes = "Да";
                else
                    diabetes = "Нет";
                end
                if (app.hypertoniaCheckBox.Value == 1)
                    hypertonia = "Да";
                else
                    hypertonia = "Нет";
                end

                surname = compose(app.surnameEditField.Value);
                name = compose(app.nameEditField.Value);
                lastname = compose(app.lastnameEditField.Value);
                dateFormat = 'dd.mm.yyyy';
                birth_date = datestr(app.birthDatePicker.Value, dateFormat);
                day = "0";
                gender = compose(app.genderDropDown.Value);
                pasport = compose(app.pasportEditField.Value);
                polis = compose(app.polisEditField.Value);
                reg_address = compose(app.registrationTextArea.Value);
                fact_address = compose(app.residenceTextArea.Value);
                doctor_id =  regexp(compose(app.doctorDropDown.Value),'\d*','Match');
                bm_index = num2str(app.bmIndexEditField.Value);
                extra_info = compose(app.additionalTextArea.Value);

                dataArray = [doctor_id surname name lastname birth_date day gender ...
                    pasport polis reg_address fact_address smoke cardiac_pathologies ...
                    diabetes hypertonia bm_index extra_info];
                dataTable = array2table(dataArray,'VariableNames',{'doctor_id',...
                    'surname','name','lastname','birth_date', 'day', 'gender',...
                    'pasport','polis','reg_address','fact_address','smoke',...
                    'cardiac_pathologies','diabetes','hypertonia','bm_index',...
                    'extra_info'});
                dbfile = fullfile(pwd,"database.db"); 
                conn = sqlite(dbfile);
                sqlwrite(conn,"Patients", dataTable);
                close(conn);
                dialog = uiconfirm(app.UIFigure,'Данные записаны успешно!', 'Добавление пациента в базу', ...
                    'Options', {'OK'});
            else
                dialog = uiconfirm(app.UIFigure,'Вы заполнили не все поля!', 'Добавление пациента в базу', ...
                    'Options', {'OK'});
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Добавление нового пациента в базу данных';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Персональные данные';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Фамилия';
            app.surnameEditField = uieditfield(app.Panel, 'text');
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'Имя';
            app.nameEditField = uieditfield(app.Panel, 'text');
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = 'Отчество';
            app.lastnameEditField = uieditfield(app.Panel, 'text');
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = 'Дата рождения';
            app.birthDatePicker = uidatepicker(app.Panel);
            app.birthDatePicker.DisplayFormat = 'dd.MM.uuuu';
            app.birthDatePicker.Placeholder = 'дд.ММ.гггг';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = 'Пол';
            app.genderDropDown = uidropdown(app.Panel);
            app.genderDropDown.Items = {'Мужской', 'Женский'};
            app.genderDropDown.Value = 'Мужской';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = 'Номер паспорта';
            app.pasportEditField = uieditfield(app.Panel, 'text');
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = 'Полис ОМС';
            app.polisEditField = uieditfield(app.Panel, 'text');
            app.Label_12 = uilabel(app.Panel);
            app.Label_12.Text = 'Адрес регистрации';
            app.registrationTextArea = uitextarea(app.Panel);
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = 'Адрес проживания';
            app.residenceTextArea = uitextarea(app.Panel);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Медицинская информация';
            app.smokeCheckBox = uicheckbox(app.Panel_2);
            app.smokeCheckBox.Text = 'Курение';
            app.cardioCheckBox = uicheckbox(app.Panel_2);
            app.cardioCheckBox.Text = 'Сердечно-сосудистые заболевания';
            app.diabetesCheckBox = uicheckbox(app.Panel_2);
            app.diabetesCheckBox.Text = 'Сахарный диабет';
            app.hypertoniaCheckBox = uicheckbox(app.Panel_2);
            app.hypertoniaCheckBox.Text = 'Гипертоническая болезнь';
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = 'Индекс массы тела';
            app.bmIndexEditField = uieditfield(app.Panel_2, 'numeric');
            app.Label_15 = uilabel(app.Panel_2);
            app.Label_15.Text = 'Лечащий врач';
            app.doctorDropDown = uidropdown(app.Panel_2);
            app.doctorDropDown.Items = {};
            app.doctorDropDown.Value = {};
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = 'Дополнительная информация';
            app.additionalTextArea = uitextarea(app.UIFigure);
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Добавить пациента';
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.exitButton.Text = 'Выход';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewPatientApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end