classdef ViewDBApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        resultTable       matlab.ui.control.Table
        Button            matlab.ui.control.Button
        protocolsUITable  matlab.ui.control.Table
        infoUITable       matlab.ui.control.Table
        patientsUITable   matlab.ui.control.Table
        exitButton        matlab.ui.control.Button
        Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Окно, из которого было открыто данное окно
        doctorsTable
        doctorFIO
        patientsTable
        protocolsTable
        doctorID
        selectedID
        dbfile
        dbknow
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, doctor_id)
            app.CallingApp = MainApp;
            app.doctorID = doctor_id;
            app.dbfile = fullfile(pwd,"database.db"); 
            conn = sqlite(app.dbfile);
            %app.doctorsTable = sqlread(conn,"Doctors");
            %app.patientsTable = sqlread(conn,"Patients");
            %app.protocolsTable = sqlread(conn,"Protocols");
            selectquery = "SELECT * FROM Patients WHERE doctor_id = " + app.doctorID;
            app.patientsTable = fetch(conn,selectquery);
            close(conn);
            
            croppedPatientsTable = removevars(app.patientsTable,{'doctor_id',...
                    'polis','reg_address', 'fact_address','smoke',...
                    'cardiac_pathologies','diabetes','hypertonia','bm_index',...
                    'extra_info','day'});
            set(app.patientsUITable, 'Data', croppedPatientsTable);
        end

        % Callback function: UIFigure, exitButton
        function UIFigureCloseRequest(app, event)
            app.CallingApp.viewDBButton.Enable = 'on';
            delete(app)
        end

        % Selection changed function: patientsUITable
        function patientsUITableSelectionChanged(app, event)
            selection = app.patientsUITable.Selection;
            selectedRow = selection(1);
            app.selectedID = app.patientsTable.id(selectedRow);
            selectedDoctorID = app.patientsTable.doctor_id(selectedRow);
            conn = sqlite(app.dbfile);
            selectquery = "SELECT * FROM Patients WHERE id = " + num2str(app.selectedID);
            selectedData = fetch(conn,selectquery);
            selectquery = "SELECT * FROM Doctors WHERE id = " + num2str(selectedDoctorID);
            selectedDoctorData = fetch(conn,selectquery);
            close(conn);
            app.doctorFIO = selectedDoctorData.surname(1)+" "+selectedDoctorData.name(1)+...
                " "+selectedDoctorData.lastname(1);
            infoArray = ["id" selectedData.id(1);...
                         "Фамилия" selectedData.surname(1);...
                         "Имя" selectedData.name(1);...
                         "Отчество" selectedData.lastname(1);...
                         "Дата рождения" selectedData.birth_date(1);...
                         "Пол" selectedData.gender(1);...
                         "Лечащий врач" app.doctorFIO;...
                         "Номер паспорта" selectedData.pasport(1);...
                         "Номер полиса ОМС" selectedData.polis(1);...
                         "Адрес регистрации" selectedData.reg_address(1);...
                         "Адрес проживания" selectedData.fact_address(1);...
                         "Курит" selectedData.smoke(1);...
                         "Имеются сердечно-сосудистые заболевания" selectedData.cardiac_pathologies(1);...
                         "Сахарный диабет" selectedData.diabetes(1);...
                         "Гипертония" selectedData.hypertonia(1);...
                         "ИМТ" selectedData.bm_index(1);...
                         "Дополнительная информация" selectedData.extra_info(1)];
            set(app.infoUITable, 'Data', array2table(infoArray));

            conn = sqlite(app.dbfile);
            selectquery = "SELECT * FROM Protocols WHERE patient_id = " + num2str(app.selectedID);
            protTable = fetch(conn,selectquery);
            close(conn);
            croppedProtTable = removevars(protTable,{'id', 'patient_id', 'day'});
            set(app.protocolsUITable, 'Data', croppedProtTable);

            app.dbfile = fullfile(pwd,"database.db"); 
            conn = sqlite(app.dbfile);
            selectquery = "SELECT date, result FROM Results WHERE patient_id = " + num2str(app.selectedID);
            protTable = fetch(conn,selectquery);
            close(conn);
            app.dbknow = fullfile(pwd,"knowledgeBase.db"); 
            conn = sqlite(app.dbknow);
            selectquery = "SELECT recomendation FROM recomendation WHERE result = '" + protTable.result + "'";
            for i = 1:selectquery.length
                rec(i,1) = fetch(conn,selectquery(i));
            end
            protTable.recomendation = rec.recomendation;
            close(conn);


            set(app.resultTable, 'Data', protTable);

            app.dynamicButton.Enable = "on";

        end

        % Callback function
        function dynamicButtonPushed(app, event)
                   

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uiputfile('Результаты.xlsx');
            if isequal(file,0)
                % отмена выбора файла
            else
                directory = fullfile(path,file);
                patientsColNames = ["ID пациента" "ID врача" "Фамилия" ...
                    "Имя" "Отчество" "Дата рождения" "Пол" "Пройдено дней" ...
                    "Номер паспорта" "Номер полиса ОМС" "Адрес регистрации" ...
                    "Адрес проживания" "Курит" "Имеются сердечно-сосудистые заболевания" ...
                    "Сахарный диабет" "Гипертония" "ИМТ" "Дополнительная информация"];
                protocolsColNames = ["ID протокола" "ID пациента" "День" "Дата" "Время" "Температура" ...
                    "Сатурация" "Сист. давл-е" "Диаст. давл-е" "Наличие кашля" ...
                    "Утомляемость" "Потеря запахов" "Дирея/тошнота" "Одышка" ...
                    "Потеря аппетита" "Головная боль" "Боль в мышцах" ...
                    "Сдавление гр.клет." "Комментарий"];
                warning('off','MATLAB:xlswrite:AddSheet'); %optional
                patientsPiece = app.patientsTable;
                patientsPiece.Properties.VariableNames = patientsColNames;
                writetable(patientsPiece,directory,'Sheet','Пациенты');
                ids = app.patientsTable.id;
                conn = sqlite(app.dbfile);
                for i = 1:height(ids)
                    selectquery = "SELECT * FROM Protocols WHERE patient_id = " + ids(i);
                    protPiece = fetch(conn,selectquery);
                    protPiece.Properties.VariableNames = protocolsColNames;
                    writetable(protPiece,directory,'Sheet','Протоколы','WriteMode','Append');
                end
                close(conn);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Просмотр базы данных';
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.exitButton.Text = 'Выход';
            app.patientsUITable = uitable(app.UIFigure);
            app.patientsUITable.ColumnName = {'Идентификатор'; 'Фамилия'; 'Имя'; 'Отчество'; 'Дата рождения'; 'Пол'; 'Паспорт'};
            app.patientsUITable.RowName = {};
            app.patientsUITable.ColumnSortable = [true false false false false false false];
            app.patientsUITable.SelectionChangedFcn = createCallbackFcn(app, @patientsUITableSelectionChanged, true);
            app.infoUITable = uitable(app.UIFigure);
            app.infoUITable.ColumnName = {'Параметр'; 'Значение'};
            app.infoUITable.RowName = {};
            app.protocolsUITable = uitable(app.UIFigure);
            app.protocolsUITable.ColumnName = {'Дата'; 'Время'; 'Температура'; 'Сатурация'; 'Сист. давл-е'; 'Диаст. давл-е'; 'Наличие кашля'; 'Утомляемость'; 'Потеря запахов'; 'Дирея/тошнота'; 'Одышка'; 'Потеря аппетита'; 'Головная боль'; 'Боль в мышцах'; 'Сдавление гр.клет.'; 'Комментарий'};
            app.protocolsUITable.RowName = {};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Сохранить ';
            app.resultTable = uitable(app.UIFigure);
            app.resultTable.ColumnName = {'Дата'; 'Диагноз'; 'Заключение'};
            app.resultTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewDBApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end