classdef labInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Panel             matlab.ui.container.Panel
        patientLabel      matlab.ui.control.Label
        startButton       matlab.ui.control.Button
        pLabel            matlab.ui.control.Label
        findButton        matlab.ui.control.Button
        pasportEditField  matlab.ui.control.EditField
        Label_8           matlab.ui.control.Label
        saveButton        matlab.ui.control.Button
        ALAT              matlab.ui.control.NumericEditField
        Label_7           matlab.ui.control.Label
        Ley               matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        Hem               matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        SOE               matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        IgG               matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        IgM               matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp_2  % 
        patientID
        soeRes
        leyRes
        hemRes
        alatRes
        igmRes
        iggRes
        dbknow
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CallingApp_2 = MainApp;
        end

        % Button pushed function: findButton
        function findButtonPushed(app, event)
            inputNumber = app.pasportEditField.Value;
            dbfile = fullfile(pwd,"database.db"); 
            conn = sqlite(dbfile);
            selectquery = "SELECT * FROM Patients WHERE pasport = " + inputNumber;
            selectedData = fetch(conn,selectquery);
            close(conn);
            if height(selectedData) ~= 0
                patientFIO = selectedData.surname(1)+" "+selectedData.name(1)+...
                    " "+selectedData.lastname(1);
                app.patientID = selectedData.id(1);
                app.patientLabel.Text = patientFIO + " ID: " + app.patientID;
                app.startButton.Enable = "on";
            else
                app.startButton.Enable = "off";
                app.patientLabel.Text = "Не найден";
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.findButton.Enable = "off";
            app.startButton.Enable = "off";
            app.saveButton.Enable = "on";

            app.ALAT.Enable = "on";
            app.IgG.Enable = "on";
            app.IgM.Enable = "on";
            app.Hem.Enable = "on";
            app.Ley.Enable = "on";
            app.SOE.Enable = "on";

            app.igmRes = 0;
            app.iggRes = 0;
            app.soeRes = 0;
            app.hemRes = 0;
            app.leyRes = 0;
            app.alatRes = 0;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.igmRes = app.IgM.Value;
            app.iggRes = app.IgG.Value;
            app.soeRes = app.SOE.Value;
            app.hemRes = app.Hem.Value;
            app.leyRes = app.Ley.Value;
            app.alatRes = app.ALAT.Value;
            
            dateFormat = 'dd.mm.yyyy';
            timeFormat = 'HH:MM';
            date = datestr(now,dateFormat);
            time = datestr(now,timeFormat);
            dataArray = [app.patientID string(date) string(time) app.igmRes ...
                    app.iggRes app.soeRes app.hemRes ...
                    app.leyRes app.alatRes];
            dataTable = array2table(dataArray,'VariableNames',{'patient_id',...
                'date','time','IgM', 'IgG', 'SOE',...
                'Hemoglabin','Leycocit','ALAT'});
            dbfile = fullfile(pwd,"database.db"); 
            conn = sqlite(dbfile);
            sqlwrite(conn,"Analyzes", dataTable);

            
            ill = "результат";
            if app.igmRes < 2 && app.iggRes <10
                ill = "не болел";
            end
            if app.igmRes >= 2 && app.iggRes < 10
                ill = "болен";
            end
            if app.igmRes < 2 && app.iggRes >= 10
                ill = "переболел";
            end
            if app.igmRes >= 2 && app.iggRes >= 10
                ill = "доп. обследование";
            end
            
            dataArray = [app.patientID string(date) ill];
            dataTable = array2table(dataArray,'VariableNames',{'patient_id','date', 'result'});
            sqlwrite(conn,"Results", dataTable);
            close(conn);

            dialog = uiconfirm(app.UIFigure,'Данные записаны успешно!', 'Добавление протокола в базу', ...
                    'Options', {'OK'});
            app.CallingApp_2.addRes.Enable = "on";
            delete(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             app.CallingApp_2.addRes.Enable = "on";
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'IgM, Ед/мл';
            app.IgM = uieditfield(app.UIFigure, 'numeric');
            app.IgM.Enable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'IgG, Ед/мл';
            app.IgG = uieditfield(app.UIFigure, 'numeric');
            app.IgG.Enable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Введите результаты анализов';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'СОЭ, Мм/ч';
            app.SOE = uieditfield(app.UIFigure, 'numeric');
            app.SOE.Enable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Гемоглабин, Г/л';
            app.Hem = uieditfield(app.UIFigure, 'numeric');
            app.Hem.Enable = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Лейкоциты, 10^3/л';
            app.Ley = uieditfield(app.UIFigure, 'numeric');
            app.Ley.Enable = 'off';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'АЛАТ, Ед/л';
            app.ALAT = uieditfield(app.UIFigure, 'numeric');
            app.ALAT.Enable = 'off';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Добавить';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Выбор пациента';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = {'Номер'; 'паспорта'};
            app.pasportEditField = uieditfield(app.Panel, 'text');
            app.findButton = uibutton(app.Panel, 'push');
            app.findButton.ButtonPushedFcn = createCallbackFcn(app, @findButtonPushed, true);
            app.findButton.Text = 'Найти';
            app.pLabel = uilabel(app.Panel);
            app.pLabel.Text = 'Пациент:';
            app.startButton = uibutton(app.Panel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Enable = 'off';
            app.startButton.Text = 'Начать';
            app.patientLabel = uilabel(app.Panel);
            app.patientLabel.Text = 'Не найден';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end